classdef Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FormationSettingPanel      matlab.ui.container.Panel
        sumCheckIntro              matlab.ui.control.TextArea
        TeamBEditField             matlab.ui.control.NumericEditField
        TeamBEditFieldLabel        matlab.ui.control.Label
        TeamAEditField             matlab.ui.control.NumericEditField
        TeamAEditFieldLabel        matlab.ui.control.Label
        Lamp_2                     matlab.ui.control.Lamp
        Lamp                       matlab.ui.control.Lamp
        checkSum                   matlab.ui.control.Label
        p3bEditField               matlab.ui.control.NumericEditField
        P3Label_2                  matlab.ui.control.Label
        p2bEditField               matlab.ui.control.NumericEditField
        P2Label_2                  matlab.ui.control.Label
        p1bEditField               matlab.ui.control.NumericEditField
        P1EditFieldLabel_2         matlab.ui.control.Label
        TeamBFormationLabel        matlab.ui.control.Label
        p3aEditField               matlab.ui.control.NumericEditField
        P3Label                    matlab.ui.control.Label
        p2aEditField               matlab.ui.control.NumericEditField
        P2Label                    matlab.ui.control.Label
        p1aEditField               matlab.ui.control.NumericEditField
        P1EditFieldLabel           matlab.ui.control.Label
        TeamAFormationLabel        matlab.ui.control.Label
        formationIntro             matlab.ui.control.TextArea
        WeatherSettingButtonGroup  matlab.ui.container.ButtonGroup
        weatherIntro               matlab.ui.control.TextArea
        SnowyButton_2              matlab.ui.control.RadioButton
        RainyButton_2              matlab.ui.control.RadioButton
        SunnyButton_2              matlab.ui.control.RadioButton
        TextArea_3                 matlab.ui.control.TextArea
        SetFormationRunButton      matlab.ui.control.Button
        ResetGameRunButton         matlab.ui.control.Button
        EndButton                  matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            global isPaused;
            isPaused = true;
            pause;
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            global isPaused;
            isPaused = true; % false to resume, true to end
            close
        end

        % Button pushed function: ResetGameRunButton
        function ResetGameRunButtonPushed(app, event)
            global isPaused;
            isPaused = true;
            SetFormationRunButtonPushed(app, event)
        end

        % Button pushed function: SetFormationRunButton
        function SetFormationRunButtonPushed(app, event)
            global isPaused;
            isPaused = false;
            p1a = app.p1aEditField.Value;
            p2a = app.p2aEditField.Value;
            p3a = app.p3aEditField.Value;
            p1b = app.p1bEditField.Value;
            p2b = app.p2bEditField.Value;
            p3b = app.p3bEditField.Value;
            pos_team_sum1 = p1a+p2a+p3a+1;
            pos_team_sum2 = p1b+p2b+p3b+1;
            app.TeamAEditField.Value = pos_team_sum1;
            app.TeamBEditField.Value = pos_team_sum2;
            if pos_team_sum1&&pos_team_sum2 == 4
                app.Lamp.Color = 'green';
                app.Lamp_2.Color = 'green';

            elseif pos_team_sum1&&pos_team_sum2 < 4
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';

            elseif pos_team_sum1&&pos_team_sum2 > 4
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';

            end
            if pos_team_sum1&&pos_team_sum2 == 4   % run the main game
                %% 3D Parameters
                % Video
                playback_speed = 0.5;                  % Speed of playback
                tF      = 3;                            % Final time                    [s]
                fR      = 30/playback_speed;            % Frame rate                    [fps]
                dt      = 1/fR;                         % Time resolution               [s]
                ptime    = linspace(0,tF,tF*fR);         % Time

                %% 2D Parameters
                % Initialzing values
                field = [90 60];   % length and width
                kickoffTeam=0;
                formation1=[p1a p2a p3a];
                formation2=[p1b p2b p3b];

                nPlayers=sum(formation1)+sum(formation2)+2;
                attributes = [zeros(nPlayers/2,1); ones(nPlayers/2,1)];

                particleRadius = 1.8;   % the radius of robot
                frictionCoefficient = 0.85;   % the friction coefficient of the field
                yellowCardRecord = zeros(0);
                substituteMemory = zeros(nPlayers,2); % {yellowcard, substitute}
                startPositionBall = [0;0];
                startVelBall = [0;0];
                startAccBall = [0;0];

                goalsTeam1=0;
                goalsTeam2=0;

                % Timesteps of the simulation in seconds
                timeSteps = 5400;
                % The gametime elapsed between every update
                timeDelta = 1;
                % Time between drawing of each plot
                timeSync = 0.01;
                % Whit these settings one simulation will take 54 seconds
                time=0;

                global gameState; % initial: 0, ball approach goal: 1, pass ball: 2, dribble: 3, collision: 4
                global speedPlayer;
                global ballKickedPose;
                global goalPlayer;

                showSubstitute = false;
                showSubstituteTimer = 0;
                nParticles = 100; % number of initial rain drops or snowflake
                particleX = rand(nParticles, 1) * field(1); % random x positions
                particleX(1:nParticles/2) = -particleX(1:nParticles/2);  % to cover the whole field
                particleY = rand(nParticles, 1) * field(2); % random y positions
                particleY(nParticles/4:nParticles/4*3) = -particleY(nParticles/4:nParticles/4*3);  % to cover the whole field


                %% 2D Game start
                
                [y,Fs] = audioread('The Simpsons take on soccer (online-video-cutter.com).mp3');
                sound(y,Fs);
                pause(4)
                while time < timeSteps && ~isPaused
                    isGoal=false;
                    isSubstitute=false;
                    collisiontimer = 0;
                    rainMoveTimer = 0;
                    snowMoveTimer = 0;
                    speedPlayer = 0;
                    lcpIdx1=-1; % the index of one player collision in last time step
                    lcpIdx2=-1; % the index of another one collision in last time step
                    ball=zeros(3,2);
                    ball(1,:)=startPositionBall;
                    ball(2,:)=startVelBall;
                    ball(3,:)=startAccBall;
                    [players,playerOriginalPosition] = InitializePlayers(formation1, formation2, field, attributes, substituteMemory, kickoffTeam);
                    while isGoal==false && time < timeSteps && isSubstitute==false && ~isPaused
                        gameState = 0;
                        % Update the velocity and position of the players
                        % and the ball
                        [players, ball] = Update(players, ball, timeSync, timeDelta, playerOriginalPosition, frictionCoefficient);
                        % Check the collision
                        [players{1}(:,1),players{1}(:,2),cflag,cpIdx1,cpIdx2]=...
                            Collisions(players{1}(:,1),players{1}(:,2),particleRadius);
                        if gameState == 1
                            disp('Kick to goal!');
                            disp(ballKickedPose);
                            [y,Fs] = audioread('cheers.aac');
                            sound(y,Fs);
                        end
                        % Check if there is a collision foul
                        if cflag == 1
                            if lcpIdx1 == cpIdx1 && lcpIdx2 == cpIdx2 && players{3}(cpIdx1) ~= players{3}(cpIdx2)
                                collisiontimer = collisiontimer + 1;
                            else
                                collisiontimer = 0;
                            end
                            disp('collisiontimer');
                            disp(collisiontimer);
                            if collisiontimer > 15  % The threshold of yellow card
                                [y,Fs] = audioread('whistle.mp3');
                                sound(y,Fs);
                                disp('Collisions!');
                                [cpIdx1,cpIdx2,players,isSubstitute,showSubstitute,yellowCardRecord] = ...
                                    YellowCard(nPlayers,cpIdx1,cpIdx2,players,yellowCardRecord);
                                collisiontimer = 0;
                            end
                            lcpIdx1 = cpIdx1;
                            lcpIdx2 = cpIdx2;
                        else
                            collisiontimer = 0;
                        end
                        figure(1)
                        set(gcf,'Position',[630,100,900,600]);
                        PlotConField(field)
                        PlotPlayers(players,particleRadius)
                        PlotBall(ball)
                        % plot the weather indicator
                        if app.SunnyButton_2.Value
                            frictionCoefficient = 0.84;
                            % Plot the sun
                            sunPosition = [-50, 35];   % Set sun position [field(1), 2*field(2)/3]
                            sunRadius = 10;   % Set sun radius
                            sunColor = [1 1 0];   % Set sun color
                            %sunPosition(1) = mod(sunPosition(1) + timeDelta/100, field(1))+4;
                            %sunPosition(2) = 1*field(2)/2 + 30*sin(2*pi*sunPosition(1)/field(1))+3;
                            plot(sunPosition(1), sunPosition(2), 'o', 'MarkerSize', 3*sunRadius, 'MarkerFaceColor', sunColor, 'MarkerEdgeColor', sunColor)
                        elseif app.RainyButton_2.Value
                            frictionCoefficient = 0.89;
                            % Plot the rain
                            nParticles = 500; % number of rain drops
                            particleSize = 10; % size of each rain drop particle
                            if rainMoveTimer > 3
                                % update rain drop positions
                                particleX = rand(nParticles, 1) * field(1); % random x positions
                                particleX(1:nParticles/2) = -particleX(1:nParticles/2);  % to cover the whole field
                                particleY = rand(nParticles, 1) * field(2); % random y positions
                                particleY(nParticles/4:nParticles/4*3) = -particleY(nParticles/4:nParticles/4*3);  % to cover the whole field
                                rainMoveTimer = 0;
                            else
                                rainMoveTimer = rainMoveTimer + 1;
                            end
                            particleColor = [0.2 0.2 1]; % color of rain drop particles (blue)
                            % plot rain drop particles
                            scatter(particleX, particleY, particleSize, particleColor, 'filled', 'MarkerFaceAlpha',.5);
                        elseif app.SnowyButton_2.Value
                            frictionCoefficient = 0.70;
                            % Plot the snow
                            nParticles = 100; % number of snowflake particles
                            particleSize = 40; % size of each snowflake particle
                            if snowMoveTimer > 10
                                % update snowflake positions
                                particleX = rand(nParticles, 1) * field(1); % random x positions
                                particleX(1:nParticles/2) = -particleX(1:nParticles/2);  % to cover the whole field
                                particleY = rand(nParticles, 1) * field(2); % random y positions
                                particleY(nParticles/4:nParticles/4*3) = -particleY(nParticles/4:nParticles/4*3);  % to cover the whole field
                                snowMoveTimer = 0;
                            else
                                snowMoveTimer = snowMoveTimer + 1;
                            end
                            particleColor = [1 1 1]; % color of snowflake particles (white)
                            % plot snowflake particles
                            scatter(particleX, particleY, particleSize, particleColor, 'hexagram');
                        end
                        if ~isempty(yellowCardRecord)
                            lyellowCardPos = [-52.5 -38];
                            ryellowCardPos = [49.5 -38];
                            for i = 1:length(yellowCardRecord)
                                pIdx = yellowCardRecord(i);
                                if mod(pIdx, nPlayers) <= nPlayers/2
                                    rectangle('Position',[lyellowCardPos(1) lyellowCardPos(2) 3 4],'FaceColor','#FFC90E','EdgeColor','none');
                                    text(lyellowCardPos(1)+1.5,-36,num2str(pIdx),'HorizontalAlignment','center','Color','r','FontSize',12);
                                    lyellowCardPos(1) = lyellowCardPos(1) + 5;
                                else
                                    rectangle('Position',[ryellowCardPos(1) ryellowCardPos(2) 3 4],'FaceColor','#FFC90E','EdgeColor','none');
                                    text(ryellowCardPos(1)+1.5,-36,num2str(pIdx),'HorizontalAlignment','center','Color','#0070C9','FontSize',12);
                                    ryellowCardPos(1) = ryellowCardPos(1) - 5;
                                end
                            end
                        end
                        if showSubstitute
                            if showSubstituteTimer < 30
                                ycIdx = yellowCardRecord(length(yellowCardRecord));
                                newIdx = ycIdx + nPlayers;
                                text(-11,-43,'#','HorizontalAlignment','center','Color','w','FontSize',12);
                                if mod(ycIdx, nPlayers) <= nPlayers/2
                                    text(-10,-43,num2str(ycIdx),'HorizontalAlignment','left','Color','#FF5656','FontSize',12);
                                    text(17,-43,num2str(newIdx),'HorizontalAlignment','left','Color','#FF5656','FontSize',12);
                                else
                                    text(-10,-43,num2str(ycIdx),'HorizontalAlignment','left','Color','#0092FF','FontSize',12);
                                    text(17,-43,num2str(newIdx),'HorizontalAlignment','left','Color','#0092FF','FontSize',12);
                                end
                                text(-7,-43,'was substituted by #','HorizontalAlignment','left','Color','w','FontSize',12);
                                showSubstituteTimer = showSubstituteTimer + 1;
                            else
                                showSubstitute = false;
                                showSubstituteTimer = 0;
                            end
                        end
                        [isGoal, ball, players] = CheckBorders(ball, players); % Check if the ball out the border or goal
                        if isGoal
                            [y,Fs] = audioread('cheers.aac');
                            sound(y,Fs);
                            if goalPlayer <= nPlayers/2
                                text(0,-33,'Goal!','HorizontalAlignment','center','Color','red','FontSize',15);
                            else
                                text(0,-33,'Goal!','HorizontalAlignment','center','Color','blue','FontSize',15);
                            end
                        end
                        txt = {[sprintf('%02d',fix(time/60)) ':' sprintf('%02d',mod(time,60))],...
                            [num2str(goalsTeam1) '-' num2str(goalsTeam2)]};
                        text(0,33,txt,'HorizontalAlignment','center');
                        if speedPlayer ~= 0
                            text(-53,-48,'#','HorizontalAlignment','center','Color','w','FontSize',12);
                            showIdx = speedPlayer + players{4}(speedPlayer,2)*nPlayers;
                            if speedPlayer <= nPlayers/2
                                text(-52,-48,num2str(showIdx),'HorizontalAlignment','left','Color','#FF5656','FontSize',12);
                            else
                                text(-52,-48,num2str(showIdx),'HorizontalAlignment','left','Color','#0092FF','FontSize',12);
                            end
                            text(-49,-48,'running faster on the sideband!','HorizontalAlignment','left','Color','w','FontSize',12);
                            speedPlayer = 0;
                        end
                        if time == 1
                            [y,Fs] = audioread('whistle.mp3');
                            sound(y,Fs);
                        end
                        if time == 15
                            [y,Fs] = audioread('cheers.aac');
                            %[y,Fs] = audioread('cheer_start.mp3');
                            sound(y,Fs);
                        end
                        if isGoal
                            disp('goalPlayer');
                            disp(goalPlayer);
                            pause(1)
                            % 3D simulation
                            goal_center_pose = [20 -5];
                            kick_area_2d = [22.5 45; -30 30];
                            kick_area_3d = [0 20; -30 20];
                            ballKickedPose_2d = ballKickedPose;
                            disp('ballKickedPose');
                            disp(ballKickedPose);
                            if ballKickedPose_2d(1) < 0
                                ballKickedPose_2d = -ballKickedPose_2d;
                            end
                            % rescale the ball position in 3D
                            ballKickedPose_rescale = [(ballKickedPose_2d(1)-kick_area_2d(1,1))/(kick_area_2d(1,2)-kick_area_2d(1,1))*(kick_area_3d(1,2)-kick_area_3d(1,1))+kick_area_3d(1,1) (ballKickedPose_2d(2)-kick_area_2d(2,1))/(kick_area_2d(2,2)-kick_area_2d(2,1))*(kick_area_3d(2,2)-kick_area_3d(2,1))+kick_area_3d(2,1)];
                            disp('ballKickedPose_rescale');
                            disp(ballKickedPose_rescale);
                            % Ball position [x y z]
                            ballKickedPose_3d = [ballKickedPose_rescale 0];
                            disp(ballKickedPose_3d);
                            % The angle the ball is kicked is calculated to the goal center
                            phi_degree = atan((goal_center_pose(2)-ballKickedPose_rescale(2))/(goal_center_pose(1)-ballKickedPose_rescale(1)))/pi*180 - 5;
                            % The height the ball is kicked is random within a certain range
                            theta_rd = 7 + 8*rand(1);  % value range: 7~15
                            ball_speed = 40;
                            % Ball initial velocity components x, y and z
                            vx_3d = ball_speed*cos(theta_rd*pi/180)*cos(phi_degree*pi/180);
                            vy_3d = ball_speed*cos(theta_rd*pi/180)*sin(phi_degree*pi/180);
                            vz_3d = ball_speed*sin(theta_rd*pi/180);
                            % Ball velocity array
                            ball_velocity_3d = [vx_3d vy_3d vz_3d];
                            % Initial conditions
                            goal_state = [ballKickedPose_3d ball_velocity_3d];
                            options = odeset('Events',@(t,y) ball_floor_or_end(t,y,goal_center_pose(1)),'RelTol',1e-6);
                            [tout,yout] = ode45(@ball_dynamics,ptime,goal_state,options);
                            % Retrieving states
                            x = yout(:,1);
                            y = yout(:,2);
                            z = yout(:,3);
                            dx = yout(:,4);
                            dy = yout(:,5);
                            dz = yout(:,6);
                            figure(1)   %%%%%%% Control to show the figure in the same window (1) or open another one (2)
                            % Create and open video writer object
                            v = VideoWriter('free_kick.mp4','MPEG-4');
                            v.Quality = 100;
                            % v.FrameRate = fR;
                            open(v);
                            for i=1:length(tout)
                                set(gca,'Color','#32CD32');
                                set(gcf,'Color','black');
                                cla
                                hold on ; grid on ; axis equal
                                set(gca,'FontName','Verdana','FontSize',12)
                                plot_field([-206.4874 199.9462 79.6781],goal_center_pose(1),goal_center_pose(2), goal_state(1:3))
                                plot3(x(1:i),y(1:i),z(1:i),'r','LineWidth',2,'Color','yellow')
                                plot3(x(i),y(i),z(i),'bo','MarkerFaceColor','#C3C3C3','MarkerSize',8)
                                xlabel('');
                                ylabel('');
                                zlabel('');
                                xticks([-10 0 10 20])
                                xticklabels({'','','',''})
                                yticks([-30 -20 -10 0 10 20])
                                yticklabels({'','','','','',''})
                                zticks([0 5])
                                zticklabels({'',''})
                                goalPlayerIdx = goalPlayer + players{4}(goalPlayer,2)*nPlayers;
                                titleStr = ['Replay Goal from player #' num2str(goalPlayerIdx)];
                                if goalPlayer <= nPlayers/2
                                    title(titleStr,'Color','#FF5656')
                                else
                                    title(titleStr,'Color','#0092FF')
                                end
                                frame = getframe(gcf);
                                writeVideo(v,frame);
                                if z(i) <= 0.5 && x(i) >= goal_center_pose(1)
                                    break
                                end
                            end
                            close(v);
                            pause(3)   %%%%%%% The displaying time (normal is 3 second)
                            [goalsTeam1,goalsTeam2,kickoffTeam] = UpdateScore(ball,goalsTeam1,goalsTeam2);
                        end
                        time=time+1;
                    end
                    substituteMemory = players{4};
                end
                disp('The end of the game');
                clf
                PlotConField(field)
                PlotPlayers(players,particleRadius)
            end

            function plot_field(camera_position,goal_lon_pos,goal_lat_pos, ball_position)
                % Goal
                goal_width      = 16.32; % [m]
                goal_height     = 4.04; % [m]
                % Penalty area
                penalty_area_width  = 40.2; % [m]
                penalty_area_length = 16.5; % [m]
                % Goal area
                goal_area_width  = 22.3;    %[m]
                goal_area_length = 8.25;     %[m]
                % Penalty mark
                penalty_mark_distance_from_goal = 12; %[m]
                % Penalty arc
                penalty_arc_radius      = 9.15;
                %     penalty_arc_center_x    = goal_lon_pos-penalty_mark_distance_from_goal;
                %     penalty_arc_center_y    = 0;
                theta_amp = acos((penalty_area_length-penalty_mark_distance_from_goal)/penalty_arc_radius);
                theta = linspace(-theta_amp+pi,theta_amp+pi,10);
                x_arc = penalty_arc_radius*cos(theta)+(goal_lon_pos-penalty_mark_distance_from_goal);
                y_arc = penalty_arc_radius*sin(theta);
                % Endline
                endline_width = 50;

                set(gca,'CameraPosition',camera_position)
                set(gca,'xlim',[-10 goal_lon_pos+5],'ylim',[-endline_width/2+goal_lat_pos endline_width/2+goal_lat_pos],'zlim',[0 6])
                % Goal
                plot3(  goal_lon_pos*ones(4,1),...
                    [-goal_width/2+goal_lat_pos -goal_width/2+goal_lat_pos goal_width/2+goal_lat_pos goal_width/2+goal_lat_pos],...
                    [0 goal_height goal_height 0],'k','LineWidth',2)
                % Penalty area
                plot3(  [goal_lon_pos goal_lon_pos-penalty_area_length goal_lon_pos-penalty_area_length goal_lon_pos],...
                    [-penalty_area_width/2+goal_lat_pos -penalty_area_width/2+goal_lat_pos penalty_area_width/2+goal_lat_pos penalty_area_width/2+goal_lat_pos],...
                    [0 0 0 0],'k')
                % Goal area
                plot3(  [goal_lon_pos goal_lon_pos-goal_area_length goal_lon_pos-goal_area_length goal_lon_pos],...
                    [-goal_area_width/2+goal_lat_pos -goal_area_width/2+goal_lat_pos goal_area_width/2+goal_lat_pos goal_area_width/2+goal_lat_pos],...
                    [0 0 0 0],'k')
                % Penalty mark
                plot3(goal_lon_pos-penalty_mark_distance_from_goal,goal_lat_pos,0,'ko','MarkerFaceColor','k','MarkerSize',2)
                % Penalty arc
                plot3(x_arc,y_arc+goal_lat_pos,zeros(length(theta)),'k')
                % Endline
                plot3([goal_lon_pos goal_lon_pos],[-endline_width/2+goal_lat_pos endline_width/2+goal_lat_pos],[0 0],'k')
                % Ball initial position
                plot3(ball_position(1), ball_position(2), ball_position(3),'ko','MarkerFaceColor','k','MarkerSize',2)
            end

            function dstates = ball_dynamics(~,states)
                % States
                % x   = states(1);
                % y   = states(2);
                % z   = states(3);
                dx  = states(4);
                dy  = states(5);
                dz  = states(6);

                v = sqrt(dx^2 + dy^2 + dz^2);   % Speed                     [m/s]

                % Parameters
                m   = 0.425;                    % Mass                      [kg]
                A   = 0.0388;                   % Cross-sectional area      [m2]
                g   = 9.8;                      % Gravity                   [m/s2]
                rho = 1.2;                      % Air density               [kg/m3]
                r   = 0.111;                    % Radius                    [m]
                CM  = 1;                        % Magnus coefficient (Magnus)
                CD  = 0.275;                    % Drag coefficient (Prandtl)

                % Ball angular velocity
                wx = 0;
                wy = 0;
                wz = 6*2*pi;        % rad/s

                % Lumped coefficients
                C1 = 1/2*CD*rho*A;
                C2 = 1/2*CM*rho*A*r;

                ddx = (-C1*v*dx + C2*(wy*dz - wz*dy))/m;
                ddy = (-C1*v*dy + C2*(wz*dx - wx*dz))/m;
                ddz = (-C1*v*dz + C2*(wx*dy - wy*dx) - m*g)/m;

                dstates = [dx ; dy ; dz ; ddx ; ddy ; ddz];

            end

            function [position,isterminal,direction] = ball_floor_or_end(~,y,goal_lon_pos)
                % Terminate simulation when ball touches the floor or YZ plane.
                position = (goal_lon_pos+5)-y(1);
                isterminal = 1;
                direction = 0;
            end

        end

        % Value changed function: p1aEditField
        function p1aEditFieldValueChanged(app, event)
            value = app.p1aEditField.Value;

        end

        % Callback function
        function P2EditFieldValueChanged2(app, event)
            value = app.P2EditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.ResetGameRunButton = uibutton(app.UIFigure, 'push');
            app.ResetGameRunButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGameRunButtonPushed, true);
            app.ResetGameRunButton.Text = 'Reset Game & Run ';
            app.SetFormationRunButton = uibutton(app.UIFigure, 'push');
            app.SetFormationRunButton.ButtonPushedFcn = createCallbackFcn(app, @SetFormationRunButtonPushed, true);
            app.SetFormationRunButton.Text = 'Set Formation & Run';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'2D SOCCER SIMULATION'};
            app.WeatherSettingButtonGroup = uibuttongroup(app.UIFigure);
            app.WeatherSettingButtonGroup.Title = 'Weather Setting';
            app.SunnyButton_2 = uiradiobutton(app.WeatherSettingButtonGroup);
            app.SunnyButton_2.Text = 'Sunny';
            app.SunnyButton_2.Value = true;
            app.RainyButton_2 = uiradiobutton(app.WeatherSettingButtonGroup);
            app.RainyButton_2.Text = 'Rainy';
            app.SnowyButton_2 = uiradiobutton(app.WeatherSettingButtonGroup);
            app.SnowyButton_2.Text = 'Snowy';
            app.weatherIntro = uitextarea(app.WeatherSettingButtonGroup);
            app.weatherIntro.Value = {'The weather condition will Influence on Football Field '};
            app.FormationSettingPanel = uipanel(app.UIFigure);
            app.FormationSettingPanel.Title = 'Formation Setting';
            app.formationIntro = uitextarea(app.FormationSettingPanel);
            app.formationIntro.Value = {'Please set the formation for the 3 players in each team.'; ''; '            e.g. 1-2-0, 3-0-0, 1-1-1,etc'};
            app.TeamAFormationLabel = uilabel(app.FormationSettingPanel);
            app.TeamAFormationLabel.Text = 'Team A:';
            app.P1EditFieldLabel = uilabel(app.FormationSettingPanel);
            app.P1EditFieldLabel.Text = 'P1';
            app.p1aEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.p1aEditField.ValueChangedFcn = createCallbackFcn(app, @p1aEditFieldValueChanged, true);
            app.P2Label = uilabel(app.FormationSettingPanel);
            app.P2Label.Text = 'P2';
            app.p2aEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.P3Label = uilabel(app.FormationSettingPanel);
            app.P3Label.Text = 'P3';
            app.p3aEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.TeamBFormationLabel = uilabel(app.FormationSettingPanel);
            app.TeamBFormationLabel.Text = 'Team B:';
            app.P1EditFieldLabel_2 = uilabel(app.FormationSettingPanel);
            app.P1EditFieldLabel_2.Text = 'P1';
            app.p1bEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.P2Label_2 = uilabel(app.FormationSettingPanel);
            app.P2Label_2.Text = 'P2';
            app.p2bEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.P3Label_2 = uilabel(app.FormationSettingPanel);
            app.P3Label_2.Text = 'P3';
            app.p3bEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.checkSum = uilabel(app.FormationSettingPanel);
            app.checkSum.Text = 'Check the total number:';
            app.Lamp = uilamp(app.FormationSettingPanel);
            app.Lamp_2 = uilamp(app.FormationSettingPanel);
            app.TeamAEditFieldLabel = uilabel(app.FormationSettingPanel);
            app.TeamAEditFieldLabel.Text = 'Team A';
            app.TeamAEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.TeamBEditFieldLabel = uilabel(app.FormationSettingPanel);
            app.TeamBEditFieldLabel.Text = 'Team B';
            app.TeamBEditField = uieditfield(app.FormationSettingPanel, 'numeric');
            app.sumCheckIntro = uitextarea(app.FormationSettingPanel);
            app.sumCheckIntro.Value = {'The total number of players in each team should be 4 (including the goalkeeper).'; ' '; 'Formation incorrect - Red Display'; 'Formation correct - Green Display'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end